classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        ImageOperationButtonGroup     matlab.ui.container.ButtonGroup
        ExecuteButton                 matlab.ui.control.Button
        bEditField_5                  matlab.ui.control.NumericEditField
        bEditField_4                  matlab.ui.control.NumericEditField
        bEditField_3                  matlab.ui.control.NumericEditField
        bEditField_2                  matlab.ui.control.NumericEditField
        ThirdImageEnhanceButton       matlab.ui.control.RadioButton
        rEditField                    matlab.ui.control.NumericEditField
        rEditFieldLabel               matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        bEditField                    matlab.ui.control.NumericEditField
        FourthImageEnhanceButton      matlab.ui.control.RadioButton
        SecondImageEnhanceButton      matlab.ui.control.RadioButton
        FirstImageEnhanceButton       matlab.ui.control.RadioButton
        ImageBrighteningLabel         matlab.ui.control.Label
        HistogramEqualizationButton   matlab.ui.control.RadioButton
        HistogramSpecificationButton  matlab.ui.control.RadioButton
        ContrastStrecthingButton      matlab.ui.control.RadioButton
        FileName                      matlab.ui.control.Label
        LoadButton                    matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        AfterBlueHist                 matlab.ui.control.UIAxes
        AfterGreenHist                matlab.ui.control.UIAxes
        AfterRedHist                  matlab.ui.control.UIAxes
        GridLayout2                   matlab.ui.container.GridLayout
        BeforeBlueHist                matlab.ui.control.UIAxes
        BeforeGreenHist               matlab.ui.control.UIAxes
        BeforeRedHist                 matlab.ui.control.UIAxes
        BeforeImageAxes               matlab.ui.control.UIAxes
        AfterImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Iimage % Image File Name
        IimagePath % Image Path
        Image % image data
        IHist % Histogram of input image
        OHist % Histogram of Output image
    end
    
    methods (Access = private)
        
        function [] = displayHist(app)
            switch size(app.Image,3)
                case 1
                    bar(app.AfterRedHist,app.OHist(:,:,1));
                case 3
                    bar(app.AfterRedHist,app.OHist(:,:,1),"red");
                    bar(app.AfterGreenHist,app.OHist(:,:,2),"green");
                    bar(app.AfterBlueHist,app.OHist(:,:,3),"blue");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [app.Iimage, app.IimagePath] = uigetfile({'*.bmp';'*.png';'*.jpg';'*.jpeg'}, ...
                'Select an image file','.\img\');
%             cla(app.AfterImageAxes,app.BeforeImageAxes);
%             cla(app.BeforeBlueHist, app.BeforeRedHist, ...
%                 app.BeforeGreenHist, ...
%                 app.AfterBlueHist, ...
%                 app.AfterGreenHist, ...
%                 app.AfterRedHist);

            imagePath = strcat(app.IimagePath,app.Iimage);
            app.Image = imread(imagePath);

            app.IHist = zeros(1,256,size(app.Image,3));
            app.OHist = zeros(1,256,size(app.Image,3));

            % ---- Plotting Histogram [PlaceHolder] ----
            for n=1:size(app.Image,3)
                app.IHist(:,:,n) = transpose(imhist(app.Image(:,:,n)));
            end
            % ------------------------------------------

            imshow(app.Image,'Parent',app.BeforeImageAxes);
            app.FileName.Text = app.Iimage;

            if size(app.Image,3) ~= 3
                bar(app.BeforeRedHist,app.IHist(:,:,1),'FaceColor',	"#080808");
            elseif size(app.Image,3) == 3
                bar(app.BeforeRedHist,app.IHist(:,:,1),'r');
                bar(app.BeforeGreenHist,app.IHist(:,:,2),'g');
                bar(app.BeforeBlueHist,app.IHist(:,:,3),'b');
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            switch app.ImageOperationButtonGroup.SelectedObject
                case app.HistogramSpecificationButton
                    % Implement Here

                case app.HistogramEqualizationButton
                    % Implement Here

                case app.ContrastStrecthingButton
                    % Implement Here

                case app.FirstImageEnhanceButton
                    % Implement Here

                case app.SecondImageEnhanceButton
                    % Implement Here

                case app.ThirdImageEnhanceButton
                    % Implement Here

                case app.FourthImageEnhanceButton
                    % Implement Here
            end
            displayHist(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.AfterImageAxes = uiaxes(app.GridLayout);
            title(app.AfterImageAxes, 'After')
            zlabel(app.AfterImageAxes, 'Z')
            app.AfterImageAxes.XTick = [];
            app.AfterImageAxes.YTick = [];
            app.BeforeImageAxes = uiaxes(app.GridLayout);
            title(app.BeforeImageAxes, 'Before')
            zlabel(app.BeforeImageAxes, 'Z')
            app.BeforeImageAxes.XTick = [];
            app.BeforeImageAxes.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.BeforeRedHist = uiaxes(app.GridLayout2);
            zlabel(app.BeforeRedHist, 'Z')
            app.BeforeGreenHist = uiaxes(app.GridLayout2);
            zlabel(app.BeforeGreenHist, 'Z')
            app.BeforeBlueHist = uiaxes(app.GridLayout2);
            zlabel(app.BeforeBlueHist, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.AfterRedHist = uiaxes(app.GridLayout3);
            zlabel(app.AfterRedHist, 'Z')
            app.AfterGreenHist = uiaxes(app.GridLayout3);
            zlabel(app.AfterGreenHist, 'Z')
            app.AfterBlueHist = uiaxes(app.GridLayout3);
            zlabel(app.AfterBlueHist, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FileName = uilabel(app.Panel_2);
            app.FileName.Text = 'Image not loaded';
            app.ImageOperationButtonGroup = uibuttongroup(app.Panel_2);
            app.ImageOperationButtonGroup.Title = 'Image Operation';
            app.ImageOperationButtonGroup.Tag = 'ImgOp';
            app.ContrastStrecthingButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.ContrastStrecthingButton.Text = 'Contrast Strecthing';
            app.ContrastStrecthingButton.Value = true;
            app.HistogramSpecificationButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.HistogramSpecificationButton.Text = 'Histogram Specification';
            app.HistogramEqualizationButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.ImageBrighteningLabel = uilabel(app.ImageOperationButtonGroup);
            app.ImageBrighteningLabel.Text = 'Image Brightening';
            app.FirstImageEnhanceButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.FirstImageEnhanceButton.Text = 's = r + ';
            app.SecondImageEnhanceButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.SecondImageEnhanceButton.Text = 's = ';
            app.FourthImageEnhanceButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.FourthImageEnhanceButton.Text = 's =              r ';
            app.bEditField = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.EditField = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.rEditFieldLabel = uilabel(app.ImageOperationButtonGroup);
            app.rEditFieldLabel.Text = 'r + ';
            app.rEditField = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.ThirdImageEnhanceButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.ThirdImageEnhanceButton.Text = 's =              log(l +               )';
            app.bEditField_2 = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.bEditField_3 = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.bEditField_4 = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.bEditField_5 = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.ExecuteButton = uibutton(app.ImageOperationButtonGroup, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end