classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GrayscaleHistogramTab        matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        FileName                     matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        HistAxes                     matlab.ui.control.UIAxes
        ImageTab1Axes                matlab.ui.control.UIAxes
        ImageEnhancementHistogramEqualizationTab  matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        ImageOperationButtonGroup    matlab.ui.container.ButtonGroup
        ResetButton                  matlab.ui.control.Button
        ExecuteButton                matlab.ui.control.Button
        GammaPowerInput              matlab.ui.control.NumericEditField
        CPowerInput                  matlab.ui.control.NumericEditField
        FourthImageEnhanceButton     matlab.ui.control.RadioButton
        CLogInput                    matlab.ui.control.NumericEditField
        ThirdImageEnhanceButton      matlab.ui.control.RadioButton
        BMultInput                   matlab.ui.control.NumericEditField
        rEditFieldLabel              matlab.ui.control.Label
        AMultInput                   matlab.ui.control.NumericEditField
        SecondImageEnhanceButton     matlab.ui.control.RadioButton
        ImageEnhancementLabel        matlab.ui.control.Label
        FIEinput                     matlab.ui.control.NumericEditField
        FirstImageEnhanceButton      matlab.ui.control.RadioButton
        ContrastStrecthingButton     matlab.ui.control.RadioButton
        HistogramEqualizationButton  matlab.ui.control.RadioButton
        GridLayout4                  matlab.ui.container.GridLayout
        OutputTab2Hist               matlab.ui.container.GridLayout
        BlueOutputTab2Axes           matlab.ui.control.UIAxes
        GreenOutputTab2Axes          matlab.ui.control.UIAxes
        RedOutputTab2Axes            matlab.ui.control.UIAxes
        InputTab2Hist                matlab.ui.container.GridLayout
        BlueInputTab2Axes            matlab.ui.control.UIAxes
        GreenInputTab2Axes           matlab.ui.control.UIAxes
        RedInputTab2Axes             matlab.ui.control.UIAxes
        OutputImageTab2Axes          matlab.ui.control.UIAxes
        InputImageTab2Axes           matlab.ui.control.UIAxes
        Panel                        matlab.ui.container.Panel
        FilenameTab2                 matlab.ui.control.Label
        LoadTab2Button               matlab.ui.control.Button
        HistogramSpecificationTab    matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout9                  matlab.ui.container.GridLayout
        BlueRefTab3Axes              matlab.ui.control.UIAxes
        GreenRefTab3Axes             matlab.ui.control.UIAxes
        RedRefTab3Axes               matlab.ui.control.UIAxes
        GridLayout8                  matlab.ui.container.GridLayout
        BlueInputTab3Axes            matlab.ui.control.UIAxes
        GreenInputTab3Axes           matlab.ui.control.UIAxes
        RedInputTab3Axes             matlab.ui.control.UIAxes
        GridLayout7                  matlab.ui.container.GridLayout
        BlueOutputTab3Axes           matlab.ui.control.UIAxes
        GreenOutputTab3Axes          matlab.ui.control.UIAxes
        RedOutputTab3Axes            matlab.ui.control.UIAxes
        Panel_4                      matlab.ui.container.Panel
        RefImageLabel                matlab.ui.control.Label
        LoadButton_3                 matlab.ui.control.Button
        RefImageTab3Axes             matlab.ui.control.UIAxes
        Panel_3                      matlab.ui.container.Panel
        InputImageLabel              matlab.ui.control.Label
        LoadButton_2                 matlab.ui.control.Button
        InputImageTab3Axes           matlab.ui.control.UIAxes
        OutputImageTab3Axes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageTab1 % Image from tab 1
        ImageTab2 % Image from tab 2
        ImageTab3 % Image from tab 3
        ImageRefTab3 % Image Ref from tab 3
        ImageOutputTab2 % Image Output from tab 2
        ImageOutputTab3 % Image Output from tab 3

        InputHistTab2
        InputHistTab3
        OutputHistTab3
        RefHistTab3
    end
    
    methods (Access = private)
        
        function [] = executeHistSpec(app)
            if isempty(app.ImageTab3) || isempty(app.ImageRefTab3)
                return
            end

            app.OutputHistTab3 = specifyHistogram(app.InputHistTab3,app.RefHistTab3);
            Oimage = mapHistogram(app.ImageTab3,app.OutputHistTab3);
%             Oimage = HistSpec(app.ImageTab3,app.ImageRefTab3);
            app.OutputHistTab3 = pxlFreq(Oimage);
            imshow(Oimage,'Parent',app.OutputImageTab3Axes);

            switch size(Oimage,3)
                case 1
                    bar(app.RedOutputTab3Axes,app.OutputHistTab3,'FaceColor',"#080808");
                    bar(app.GreenOutputTab3Axes,app.OutputHistTab3,'FaceColor',"#080808");
                    bar(app.BlueOutputTab3Axes,app.OutputHistTab3,'FaceColor',"#080808");
                case 3
                    bar(app.RedOutputTab3Axes,app.OutputHistTab3(1,:),"red");
                    bar(app.GreenOutputTab3Axes,app.OutputHistTab3(2,:),"green");
                    bar(app.BlueOutputTab3Axes,app.OutputHistTab3(3,:),"blue");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.bmp';'*.png';'*.jpg';'*.jpeg'}, ...
                'Select an image file','..\img\');
            fullpath = strcat(filepath,filename);
            app.ImageTab1 = imread(fullpath);
            app.FileName.Text = filename;
            
            hist = gscaleHist(app.ImageTab1);
            imshow(app.ImageTab1,'Parent',app.ImageTab1Axes);
            bar(app.HistAxes,hist,'FaceColor',"#080808");            
        end

        % Button pushed function: LoadTab2Button
        function LoadTab2ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.bmp';'*.png';'*.jpg';'*.jpeg'}, ...
                'Select an image file','..\img\');

            cla(app.BlueOutputTab2Axes);
            cla(app.GreenOutputTab2Axes);
            cla(app.RedOutputTab2Axes);
            cla(app.OutputImageTab2Axes);
            
            app.FilenameTab2.Text = filename;
            imagePath = strcat(filepath,filename);
            app.ImageTab2 = imread(imagePath);
            app.InputHistTab2 = pxlFreq(app.ImageTab2);

            imshow(app.ImageTab2,'Parent',app.InputImageTab2Axes);

            if size(app.ImageTab2,3) ~= 3
                bar(app.RedInputTab2Axes,app.InputHistTab2,'FaceColor',"#080808");
                bar(app.GreenInputTab2Axes,app.InputHistTab2,'FaceColor',"#080808");
                bar(app.BlueInputTab2Axes,app.InputHistTab2,'FaceColor',"#080808");
            elseif size(app.ImageTab2,3) == 3
                bar(app.RedInputTab2Axes,app.InputHistTab2(1,:),'r');
                bar(app.GreenInputTab2Axes,app.InputHistTab2(2,:),'g');
                bar(app.BlueInputTab2Axes,app.InputHistTab2(3,:),'b');
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            switch app.ImageOperationButtonGroup.SelectedObject
                case app.HistogramEqualizationButton
                    Oimage = mapHistogram(app.ImageTab2, ...
                        equalizeHistogram(app.InputHistTab2));

                case app.ContrastStrecthingButton
                    Oimage = mapHistogram(app.ImageTab2, ...
                        contrastStretch(app.InputHistTab2));

                case app.FirstImageEnhanceButton
                    Oimage = imgBright(app.ImageTab2,1, ...
                        app.FIEinput.Value);

                case app.SecondImageEnhanceButton
                    Oimage = imgBright(app.ImageTab2,app.AMultInput.Value, ...
                        app.BMultInput.Value);

                case app.ThirdImageEnhanceButton
                    Oimage = logTransform(app.ImageTab2,app.CLogInput.Value);

                case app.FourthImageEnhanceButton
                    Oimage = powerTransform(app.ImageTab2, ...
                        app.CPowerInput.Value,app.GammaPowerInput.Value);
            end

            OutputHist = pxlFreq(Oimage);
            imshow(Oimage,'Parent',app.OutputImageTab2Axes);
            switch size(Oimage,3)
                case 1
                    bar(app.RedOutputTab2Axes,OutputHist,'FaceColor',"#080808");
                    bar(app.GreenOutputTab2Axes,OutputHist,'FaceColor',"#080808");
                    bar(app.BlueOutputTab2Axes,OutputHist,'FaceColor',"#080808");
                case 3
                    bar(app.RedOutputTab2Axes,OutputHist(1,:),"red");
                    bar(app.GreenOutputTab2Axes,OutputHist(2,:),"green");
                    bar(app.BlueOutputTab2Axes,OutputHist(3,:),"blue");
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.RedOutputTab2Axes);
            cla(app.GreenOutputTab2Axes);
            cla(app.BlueOutputTab2Axes);
            cla(app.ImageOutputTab2);
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            [filename, filepath] = uigetfile({'*.bmp';'*.png';'*.jpg';'*.jpeg'}, ...
                'Select an image file','..\img\');

%             cla(app.BlueOutputTab3Axes);
%             cla(app.GreenOutputTab3Axes);
%             cla(app.RedOutputTab3Axes);
%             cla(app.OutputImageTab3Axes);
            
            app.InputImageLabel.Text = filename;
            imagePath = strcat(filepath,filename);
            app.ImageTab3 = imread(imagePath);
            app.InputHistTab3 = pxlFreq(app.ImageTab3);

            imshow(app.ImageTab3,'Parent',app.InputImageTab3Axes);

            if size(app.ImageTab3,3) ~= 3
                bar(app.RedInputTab3Axes,app.InputHistTab3,'FaceColor',"#080808");
                bar(app.GreenInputTab3Axes,app.InputHistTab3,'FaceColor',"#080808");
                bar(app.BlueInputTab3Axes,app.InputHistTab3,'FaceColor',"#080808");
            elseif size(app.ImageTab3,3) == 3
                bar(app.RedInputTab3Axes,app.InputHistTab3(1,:),'r');
                bar(app.GreenInputTab3Axes,app.InputHistTab3(2,:),'g');
                bar(app.BlueInputTab3Axes,app.InputHistTab3(3,:),'b');
            end

            executeHistSpec(app);
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            [filename, filepath] = uigetfile({'*.bmp';'*.png';'*.jpg';'*.jpeg'}, ...
                'Select an image file','..\img\');

%             cla(app.BlueOutputTab3Axes);
%             cla(app.GreenOutputTab3Axes);
%             cla(app.RedOutputTab3Axes);
%             cla(app.OutputImageTab3Axes);
            
            app.RefImageLabel.Text = filename;
            imagePath = strcat(filepath,filename);
            app.ImageRefTab3 = imread(imagePath);
            app.RefHistTab3 = pxlFreq(app.ImageRefTab3);

            imshow(app.ImageRefTab3,'Parent',app.RefImageTab3Axes);

            if size(app.ImageRefTab3,3) ~= 3
                bar(app.RedRefTab3Axes,app.RefHistTab3,'FaceColor',"#080808");
                bar(app.GreenRefTab3Axes,app.RefHistTab3,'FaceColor',"#080808");
                bar(app.BlueRefTab3Axes,app.RefHistTab3,'FaceColor',"#080808");
            elseif size(app.ImageRefTab3,3) == 3
                bar(app.RedRefTab3Axes,app.RefHistTab3(1,:),'r');
                bar(app.GreenRefTab3Axes,app.RefHistTab3(2,:),'g');
                bar(app.BlueRefTab3Axes,app.RefHistTab3(3,:),'b');
            end

            executeHistSpec(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrayscaleHistogramTab = uitab(app.TabGroup);
            app.GrayscaleHistogramTab.Title = 'Grayscale Histogram';
            app.GridLayout2 = uigridlayout(app.GrayscaleHistogramTab);
            app.ImageTab1Axes = uiaxes(app.GridLayout2);
            title(app.ImageTab1Axes, 'Input')
            zlabel(app.ImageTab1Axes, 'Z')
            app.ImageTab1Axes.XTick = [];
            app.ImageTab1Axes.YTick = [];
            app.HistAxes = uiaxes(app.GridLayout2);
            title(app.HistAxes, 'Grayscale Histogram')
            zlabel(app.HistAxes, 'Z')
            app.Panel_2 = uipanel(app.GridLayout2);
            app.LoadButton = uibutton(app.Panel_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FileName = uilabel(app.Panel_2);
            app.FileName.Text = 'No Image Loaded';
            app.ImageEnhancementHistogramEqualizationTab = uitab(app.TabGroup);
            app.ImageEnhancementHistogramEqualizationTab.Title = 'Image Enhancement & Histogram Equalization';
            app.GridLayout = uigridlayout(app.ImageEnhancementHistogramEqualizationTab);
            app.Panel = uipanel(app.GridLayout);
            app.LoadTab2Button = uibutton(app.Panel, 'push');
            app.LoadTab2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadTab2ButtonPushed, true);
            app.LoadTab2Button.Text = 'Load';
            app.FilenameTab2 = uilabel(app.Panel);
            app.FilenameTab2.Text = 'No Image Loaded';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.InputImageTab2Axes = uiaxes(app.GridLayout4);
            title(app.InputImageTab2Axes, 'Input')
            zlabel(app.InputImageTab2Axes, 'Z')
            app.InputImageTab2Axes.XTick = [];
            app.InputImageTab2Axes.YTick = [];
            app.OutputImageTab2Axes = uiaxes(app.GridLayout4);
            title(app.OutputImageTab2Axes, 'Output')
            zlabel(app.OutputImageTab2Axes, 'Z')
            app.OutputImageTab2Axes.XTick = [];
            app.OutputImageTab2Axes.YTick = [];
            app.InputTab2Hist = uigridlayout(app.GridLayout4);
            app.RedInputTab2Axes = uiaxes(app.InputTab2Hist);
            title(app.RedInputTab2Axes, 'Red')
            zlabel(app.RedInputTab2Axes, 'Z')
            app.GreenInputTab2Axes = uiaxes(app.InputTab2Hist);
            title(app.GreenInputTab2Axes, 'Green')
            zlabel(app.GreenInputTab2Axes, 'Z')
            app.BlueInputTab2Axes = uiaxes(app.InputTab2Hist);
            title(app.BlueInputTab2Axes, 'Blue')
            zlabel(app.BlueInputTab2Axes, 'Z')
            app.OutputTab2Hist = uigridlayout(app.GridLayout4);
            app.RedOutputTab2Axes = uiaxes(app.OutputTab2Hist);
            title(app.RedOutputTab2Axes, 'Red')
            zlabel(app.RedOutputTab2Axes, 'Z')
            app.GreenOutputTab2Axes = uiaxes(app.OutputTab2Hist);
            title(app.GreenOutputTab2Axes, 'Green')
            zlabel(app.GreenOutputTab2Axes, 'Z')
            app.BlueOutputTab2Axes = uiaxes(app.OutputTab2Hist);
            title(app.BlueOutputTab2Axes, 'Blue')
            zlabel(app.BlueOutputTab2Axes, 'Z')
            app.ImageOperationButtonGroup = uibuttongroup(app.GridLayout);
            app.ImageOperationButtonGroup.Title = 'Operation';
            app.HistogramEqualizationButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.HistogramEqualizationButton.Value = true;
            app.ContrastStrecthingButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.ContrastStrecthingButton.Text = 'Contrast Strecthing';
            app.FirstImageEnhanceButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.FirstImageEnhanceButton.Text = 's = r + ';
            app.FIEinput = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.ImageEnhancementLabel = uilabel(app.ImageOperationButtonGroup);
            app.ImageEnhancementLabel.Text = 'Image Enhancement';
            app.SecondImageEnhanceButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.SecondImageEnhanceButton.Text = 's = ';
            app.AMultInput = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.rEditFieldLabel = uilabel(app.ImageOperationButtonGroup);
            app.rEditFieldLabel.Text = 'r + ';
            app.BMultInput = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.ThirdImageEnhanceButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.ThirdImageEnhanceButton.Text = 's =              log(1 + r)';
            app.CLogInput = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.FourthImageEnhanceButton = uiradiobutton(app.ImageOperationButtonGroup);
            app.FourthImageEnhanceButton.Text = 's =              r ';
            app.CPowerInput = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.GammaPowerInput = uieditfield(app.ImageOperationButtonGroup, 'numeric');
            app.ExecuteButton = uibutton(app.ImageOperationButtonGroup, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ResetButton = uibutton(app.ImageOperationButtonGroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            app.GridLayout3 = uigridlayout(app.HistogramSpecificationTab);
            app.OutputImageTab3Axes = uiaxes(app.GridLayout3);
            title(app.OutputImageTab3Axes, 'Output')
            zlabel(app.OutputImageTab3Axes, 'Z')
            app.OutputImageTab3Axes.XTick = [];
            app.OutputImageTab3Axes.YTick = [];
            app.Panel_3 = uipanel(app.GridLayout3);
            app.InputImageTab3Axes = uiaxes(app.Panel_3);
            title(app.InputImageTab3Axes, 'Input')
            zlabel(app.InputImageTab3Axes, 'Z')
            app.InputImageTab3Axes.XTick = [];
            app.InputImageTab3Axes.YTick = [];
            app.LoadButton_2 = uibutton(app.Panel_3, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.InputImageLabel = uilabel(app.Panel_3);
            app.InputImageLabel.Text = 'No Image Loaded';
            app.Panel_4 = uipanel(app.GridLayout3);
            app.RefImageTab3Axes = uiaxes(app.Panel_4);
            title(app.RefImageTab3Axes, 'Reference')
            zlabel(app.RefImageTab3Axes, 'Z')
            app.RefImageTab3Axes.XTick = [];
            app.RefImageTab3Axes.YTick = [];
            app.LoadButton_3 = uibutton(app.Panel_4, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.RefImageLabel = uilabel(app.Panel_4);
            app.RefImageLabel.Text = 'No Image Loaded';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.RedOutputTab3Axes = uiaxes(app.GridLayout7);
            title(app.RedOutputTab3Axes, 'Red')
            zlabel(app.RedOutputTab3Axes, 'Z')
            app.GreenOutputTab3Axes = uiaxes(app.GridLayout7);
            title(app.GreenOutputTab3Axes, 'Green')
            zlabel(app.GreenOutputTab3Axes, 'Z')
            app.BlueOutputTab3Axes = uiaxes(app.GridLayout7);
            title(app.BlueOutputTab3Axes, 'Blue')
            zlabel(app.BlueOutputTab3Axes, 'Z')
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.RedInputTab3Axes = uiaxes(app.GridLayout8);
            title(app.RedInputTab3Axes, 'Red')
            zlabel(app.RedInputTab3Axes, 'Z')
            app.GreenInputTab3Axes = uiaxes(app.GridLayout8);
            title(app.GreenInputTab3Axes, 'Green')
            zlabel(app.GreenInputTab3Axes, 'Z')
            app.BlueInputTab3Axes = uiaxes(app.GridLayout8);
            title(app.BlueInputTab3Axes, 'Blue')
            zlabel(app.BlueInputTab3Axes, 'Z')
            app.GridLayout9 = uigridlayout(app.GridLayout3);
            app.RedRefTab3Axes = uiaxes(app.GridLayout9);
            title(app.RedRefTab3Axes, 'Red')
            zlabel(app.RedRefTab3Axes, 'Z')
            app.GreenRefTab3Axes = uiaxes(app.GridLayout9);
            title(app.GreenRefTab3Axes, 'Green')
            zlabel(app.GreenRefTab3Axes, 'Z')
            app.BlueRefTab3Axes = uiaxes(app.GridLayout9);
            title(app.BlueRefTab3Axes, 'Blue')
            zlabel(app.BlueRefTab3Axes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end